classdef WindTurbine_GUI_Eurus2026_V1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeneralMonitoringGraphsTab      matlab.ui.container.Tab
        outputPowerGraph_general        matlab.ui.control.UIAxes
        TemperatureGraph_general        matlab.ui.control.UIAxes
        RPMGraph_general                matlab.ui.control.UIAxes
        PowerElectronicsGraphsTab       matlab.ui.container.Tab
        voltageAfterConverterGraph_power  matlab.ui.control.UIAxes
        currentAfterConverterGraph_power  matlab.ui.control.UIAxes
        voltageBeforeConverterGraph_power  matlab.ui.control.UIAxes
        currentBeforeConverterGraph_power  matlab.ui.control.UIAxes
        SafteyCriticalTab               matlab.ui.container.Tab
        RPMField_saftey                 matlab.ui.control.NumericEditField
        PitchAngleField_saftey          matlab.ui.control.NumericEditField
        PitchAngleGauge_saftey          matlab.ui.control.Gauge
        PitchAngleGauge_2Label          matlab.ui.control.Label
        TemperatureField_saftey         matlab.ui.control.NumericEditField
        TemperatureGaugeLinear_saftey   matlab.ui.control.LinearGauge
        TemperatureGaugeLabel           matlab.ui.control.Label
        RPMGauge_saftey                 matlab.ui.control.Gauge
        RPMGauge_2Label                 matlab.ui.control.Label
        TemperatureGraph_saftey         matlab.ui.control.UIAxes
        LatestValuesTab                 matlab.ui.container.Tab
        RPMField_latest                 matlab.ui.control.NumericEditField
        RPMGauge_latest                 matlab.ui.control.Gauge
        RPMGaugeLabel                   matlab.ui.control.Label
        PitchAngleGauge_latest          matlab.ui.control.Gauge
        PitchAngleGaugeLabel            matlab.ui.control.Label
        PitchAngleField_latest          matlab.ui.control.NumericEditField
        TemperatureField_latest         matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel       matlab.ui.control.Label
        PowerOutputField_latest         matlab.ui.control.NumericEditField
        PowerOutputEditFieldLabel       matlab.ui.control.Label
        PostConverterVoltageField_latest  matlab.ui.control.NumericEditField
        PostConverterVoltageEditFieldLabel  matlab.ui.control.Label
        PostConvertCurrentField_latest  matlab.ui.control.NumericEditField
        PostConvertCurrentEditFieldLabel  matlab.ui.control.Label
        PreConverterVoltageField_latest  matlab.ui.control.NumericEditField
        PreConverterVoltageEditFieldLabel  matlab.ui.control.Label
        PreConverterCurrentField_latest  matlab.ui.control.NumericEditField
        PreConverterCurrentLabel        matlab.ui.control.Label
        StopSignalAcknowledgedLamp      matlab.ui.control.Lamp
        StopSignalAcknowledgedLampLabel  matlab.ui.control.Label
        ConnectedtowindturbineLamp      matlab.ui.control.Lamp
        ConnectedtowindturbineLampLabel  matlab.ui.control.Label
        ManualStopButton                matlab.ui.control.Button
        WindTurbineDashboardEurus2026Label  matlab.ui.control.Label
    end

    properties (Access = private)
            BTDevice
            StartTime
            

            % Connection watchdog variables
            isConnected = false;
            watchDogTimer;
            lastPacketTime;


            % Master Timelines
            TimeSecBuffer = []  
            TimeMinBuffer = []  
            
            % Buffers for plots 
            RPMHistory      = []
            PowerHistory    = []
            vAfterHistory   = []
            cAfterHistory   = []
            vBeforeHistory  = []
            cBeforeHistory  = []
            TempHistory     = []
        end
        
        methods (Access = private)
        
        % AI MADE FUNCTION FOR MAKING GRAPH UPDATING SMOOTH
        function updateGraphCanvas(~, ax, xData, yData, lineColor)
            plot(ax, xData, yData, 'Color', lineColor, 'LineWidth', 1.5);
            grid(ax, 'on');
        end
    
    
    function readTelemtry(app, src)
           try
               if src.NumBytesAvailable > 250
                   flush(src, "input");
               end
               
               rawAscii = readline(src);
               
               if startsWith(rawAscii, "$EURUS,")
                   data = split(rawAscii, ",");
                   
                   if length(data) == 13
                       typeA = data(2);
                       typeB = data(3);
                       typeC = data(4);
                       
                       dataValues = str2double(data(5:end));
                       
                       currRPM     = dataValues(1);
                       currPWR     = dataValues(2);
                       currCAfter  = dataValues(3);
                       currVAfter  = dataValues(4);
                       currCBefore = dataValues(5);
                       currVBefore = dataValues(6);
                       currPitch   = dataValues(7);
                       currTemp    = dataValues(8);
                       isACK       = dataValues(9);

                       %When we get sent a packet we update this
                       app.lastPacketTime = datetime('now');
                       
                       if ~any(isnan(dataValues))
                           
                           % ==========================================================
                           % STATUS 'A' GATE (2Hz Updates)
                           % ==========================================================
                           if typeA == "A"   
                              
                              % Buffer Management for Fast Plots
                              elapsedSec = seconds(datetime('now') - app.StartTime);
                              app.TimeSecBuffer(end+1)  = elapsedSec;
                              app.RPMHistory(end+1)     = currRPM;
                              app.PowerHistory(end+1)   = currPWR;
                              app.vAfterHistory(end+1)  = currVAfter;
                              app.cAfterHistory(end+1)  = currCAfter;
                              app.vBeforeHistory(end+1) = currVBefore;
                              app.cBeforeHistory(end+1) = currCBefore;
                              
                              if length(app.TimeSecBuffer) > 100
                                  app.TimeSecBuffer(1)  = [];
                                  app.RPMHistory(1)     = [];
                                  app.PowerHistory(1)   = [];
                                  app.vAfterHistory(1)  = [];
                                  app.cAfterHistory(1)  = [];
                                  app.vBeforeHistory(1) = [];
                                  app.cBeforeHistory(1) = [];
                              end

                              % -------------------------------------------------------
                              % TAB 1: General Monitoring Graphs (Group A)
                              % -------------------------------------------------------
                              updateGraphCanvas(app, app.RPMGraph_general, app.TimeSecBuffer, app.RPMHistory, [0, 0.447, 0.741]);
                              updateGraphCanvas(app, app.outputPowerGraph_general, app.TimeSecBuffer, app.PowerHistory, [0.466, 0.674, 0.188]);

                              % -------------------------------------------------------
                              % TAB 2: Power Electronics Graphs (Group A)
                              % -------------------------------------------------------
                              updateGraphCanvas(app, app.voltageBeforeConverterGraph_power, app.TimeSecBuffer, app.vBeforeHistory, [0.494, 0.184, 0.556]);
                              updateGraphCanvas(app, app.currentBeforeConverterGraph_power, app.TimeSecBuffer, app.cBeforeHistory, [0.301, 0.745, 0.933]);
                              updateGraphCanvas(app, app.voltageAfterConverterGraph_power, app.TimeSecBuffer, app.vAfterHistory, [0.85, 0.325, 0.098]);
                              updateGraphCanvas(app, app.currentAfterConverterGraph_power, app.TimeSecBuffer, app.cAfterHistory, [0.929, 0.694, 0.125]);

                              % -------------------------------------------------------
                              % TAB 3: Safety Critical (Group A)
                              % -------------------------------------------------------
                              app.RPMField_saftey.Value = currRPM;
                              app.RPMGauge_saftey.Value = currRPM;

                              % -------------------------------------------------------
                              % TAB 4: Latest Values (Group A)
                              % -------------------------------------------------------
                              app.RPMField_latest.Value                  = currRPM;
                              app.RPMGauge_latest.Value                  = currRPM;
                              app.PowerOutputField_latest.Value          = currPWR;
                              app.PostConverterVoltageField_latest.Value = currVAfter;
                              app.PostConvertCurrentField_latest.Value   = currCAfter;
                              app.PreConverterVoltageField_latest.Value  = currVBefore;
                              app.PreConverterCurrentField_latest.Value  = currCBefore;

                           end
                           
                           % ==========================================================
                           % STATUS 'B' GATE (1Hz Updates)
                           % ==========================================================
                           if typeB == "B"

                              % -------------------------------------------------------
                              % TAB 3: Safety Critical (Group B)
                              % -------------------------------------------------------
                              app.PitchAngleField_saftey.Value = currPitch;
                              app.PitchAngleGauge_saftey.Value = currPitch;
                              
                              % -------------------------------------------------------
                              % TAB 4: Latest Values (Group B)
                              % -------------------------------------------------------
                              app.PitchAngleField_latest.Value = currPitch;
                              app.PitchAngleGauge_latest.Value = currPitch;

                           end
                           
                           % ==========================================================
                           % STATUS 'C' GATE (60s Selective Render)
                           % ==========================================================
                           if typeC == "C"
                              
                              % Buffer Management for Slow Plots
                              elapsedMins = seconds(datetime('now') - app.StartTime) / 60;
                              app.TimeMinBuffer(end+1) = elapsedMins;
                              app.TempHistory(end+1)   = currTemp;
                              
                              if length(app.TimeMinBuffer) > 120
                                  app.TimeMinBuffer(1) = [];
                                  app.TempHistory(1)   = [];
                              end

                              % -------------------------------------------------------
                              % TAB 1: General Monitoring Graphs (Group C)
                              % -------------------------------------------------------
                              updateGraphCanvas(app, app.TemperatureGraph_general, app.TimeMinBuffer, app.TempHistory, [0.635, 0.078, 0.184]);

                              % -------------------------------------------------------
                              % TAB 3: Safety Critical (Group C)
                              % -------------------------------------------------------
                              app.TemperatureField_saftey.Value       = currTemp;
                              app.TemperatureGaugeLinear_saftey.Value = currTemp;
                              updateGraphCanvas(app, app.TemperatureGraph_saftey, app.TimeMinBuffer, app.TempHistory, [0.635, 0.078, 0.184]);

                              % -------------------------------------------------------
                              % TAB 4: Latest Values (Group C)
                              % -------------------------------------------------------
                              app.TemperatureField_latest.Value       = currTemp;

                           end
                           
                           % --- Global Status Indicators ---
                           if isACK == 1
                               app.StopSignalAcknowledgedLamp.Color = [0, 1, 0];
                           else
                               app.StopSignalAcknowledgedLamp.Color = [0.8, 0.8, 0.8];
                           end
                                          
                       end 
                   end
               end
           catch    
       end
    end
    
    function connection_watchDog(app)
            
            %Check if we are connected
            if ~app.isConnected
                try
                    app.BTDevice = serialport("COM6", 115200);
                    configureTerminator(app.BTDevice, "CR/LF");
                    app.BTDevice.FlowControl = "none";
                    
                    %If no error occurs connection completes
                    app.isConnected = true;
                    app.StartTime   = datetime('now');
                    app.lastPacketTime = datetime('now');
                    
                    % Indicate connection
                    app.ConnectedtowindturbineLamp.Color = [0, 1, 0]; % Bright Green
                    app.ConnectedtowindturbineLampLabel.Text = "Connected";
                    
                    configureCallback(app.BTDevice, "terminator", @(src, event) readTelemtry(app, src));
                catch
                    
                end

            %Check we are still connected 
            else
                % Calculate seconds since last packet
                secSinceLastPulse = seconds(datetime('now') - app.lastPacketTime);
                % If 3 seconds pass with no data, disconnect
                if secSinceLastPulse > 3.0
                    app.isConnected = false;
                    
                    app.ConnectedtowindturbineLamp.Color = [1, 0, 0]; 
                    app.ConnectedtowindturbineLampLabel.Text = "Attempting Connection";
                    % Close Bluetooth link and start trying to reconnect
                    try
                        delete(app.BTDevice);
                        app.BTDevice = [];
                    catch
                    end
                end
            end
            
        end    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Intially start in disconnected state
            app.ConnectedtowindturbineLamp.Color = [1, 0, 0]; 
            app.ConnectedtowindturbineLampLabel.Text = "Attempting Connection";

            % Watch dog timer will monitor connections and disconnections
            app.watchDogTimer = timer('ExecutionMode', 'fixedSpacing', ...
                                      'Period', 2.0, ...
                                      'TimerFcn', @(~,~) connection_watchDog(app));
            % Start watchdog
            start(app.watchDogTimer);
        end

        % Button pushed function: ManualStopButton
        function ManualStopButtonPushed(app, event)
            if ~isempty(app.BTDevice)
                writeline(app.BTDevice, "S")
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            if ~isempty(app.watchDogTimer)
                stop(app.watchDogTimer);
                delete(app.watchDogTimer);
            end

            if ~isempty(app.BTDevice)
                try
                    configureCallback(app.BTDevice, "off");
                    clear app.BTDevice;
                catch
                end
            end
            delete(app)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.WindTurbineDashboardEurus2026Label = uilabel(app.UIFigure);
            app.WindTurbineDashboardEurus2026Label.Text = ' Wind Turbine Dashboard (Eurus 2026)';
            app.ManualStopButton = uibutton(app.UIFigure, 'push');
            app.ManualStopButton.ButtonPushedFcn = createCallbackFcn(app, @ManualStopButtonPushed, true);
            app.ManualStopButton.Text = 'Manual Stop';
            app.ConnectedtowindturbineLampLabel = uilabel(app.UIFigure);
            app.ConnectedtowindturbineLampLabel.Text = 'Connected to wind turbine';
            app.ConnectedtowindturbineLamp = uilamp(app.UIFigure);
            app.StopSignalAcknowledgedLampLabel = uilabel(app.UIFigure);
            app.StopSignalAcknowledgedLampLabel.Text = 'Stop Signal Acknowledged';
            app.StopSignalAcknowledgedLamp = uilamp(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralMonitoringGraphsTab = uitab(app.TabGroup);
            app.GeneralMonitoringGraphsTab.Title = 'General Monitoring Graphs';
            app.RPMGraph_general = uiaxes(app.GeneralMonitoringGraphsTab);
            title(app.RPMGraph_general, 'RPM')
            xlabel(app.RPMGraph_general, 'Time (Seconds)')
            ylabel(app.RPMGraph_general, 'RPM')
            zlabel(app.RPMGraph_general, 'Z')
            app.TemperatureGraph_general = uiaxes(app.GeneralMonitoringGraphsTab);
            title(app.TemperatureGraph_general, 'Temperature')
            xlabel(app.TemperatureGraph_general, 'Time (Mintues)')
            ylabel(app.TemperatureGraph_general, 'Temperature (Celsius)')
            zlabel(app.TemperatureGraph_general, 'Z')
            app.outputPowerGraph_general = uiaxes(app.GeneralMonitoringGraphsTab);
            title(app.outputPowerGraph_general, 'Output Power')
            xlabel(app.outputPowerGraph_general, 'Time (Second)')
            ylabel(app.outputPowerGraph_general, 'Power (Watt)')
            zlabel(app.outputPowerGraph_general, 'Z')
            app.PowerElectronicsGraphsTab = uitab(app.TabGroup);
            app.PowerElectronicsGraphsTab.Title = 'Power Electronics Graphs';
            app.currentBeforeConverterGraph_power = uiaxes(app.PowerElectronicsGraphsTab);
            title(app.currentBeforeConverterGraph_power, 'Current Before Converter')
            xlabel(app.currentBeforeConverterGraph_power, 'Time (Seconds)')
            ylabel(app.currentBeforeConverterGraph_power, 'Current (A)')
            zlabel(app.currentBeforeConverterGraph_power, 'Z')
            app.voltageBeforeConverterGraph_power = uiaxes(app.PowerElectronicsGraphsTab);
            title(app.voltageBeforeConverterGraph_power, 'Voltage Before Converter')
            xlabel(app.voltageBeforeConverterGraph_power, 'Time (Seconds)')
            ylabel(app.voltageBeforeConverterGraph_power, 'Voltage (V)')
            zlabel(app.voltageBeforeConverterGraph_power, 'Z')
            app.currentAfterConverterGraph_power = uiaxes(app.PowerElectronicsGraphsTab);
            title(app.currentAfterConverterGraph_power, 'Current After Converter')
            xlabel(app.currentAfterConverterGraph_power, 'Time (Seconds)')
            ylabel(app.currentAfterConverterGraph_power, 'Current (A)')
            zlabel(app.currentAfterConverterGraph_power, 'Z')
            app.voltageAfterConverterGraph_power = uiaxes(app.PowerElectronicsGraphsTab);
            title(app.voltageAfterConverterGraph_power, 'Voltage After Converter')
            xlabel(app.voltageAfterConverterGraph_power, 'Time (Seconds)')
            ylabel(app.voltageAfterConverterGraph_power, 'Voltage (V)')
            zlabel(app.voltageAfterConverterGraph_power, 'Z')
            app.SafteyCriticalTab = uitab(app.TabGroup);
            app.SafteyCriticalTab.Title = 'Saftey Critical';
            app.TemperatureGraph_saftey = uiaxes(app.SafteyCriticalTab);
            xlabel(app.TemperatureGraph_saftey, 'Time (Mintues)')
            ylabel(app.TemperatureGraph_saftey, 'Temperature (Celsius)')
            zlabel(app.TemperatureGraph_saftey, 'Z')
            app.RPMGauge_2Label = uilabel(app.SafteyCriticalTab);
            app.RPMGauge_2Label.Text = 'RPM';
            app.RPMGauge_saftey = uigauge(app.SafteyCriticalTab, 'circular');
            app.RPMGauge_saftey.Limits = [0 600];
            app.TemperatureGaugeLabel = uilabel(app.SafteyCriticalTab);
            app.TemperatureGaugeLabel.Text = 'Temperature';
            app.TemperatureGaugeLinear_saftey = uigauge(app.SafteyCriticalTab, 'linear');
            app.TemperatureField_saftey = uieditfield(app.SafteyCriticalTab, 'numeric');
            app.PitchAngleGauge_2Label = uilabel(app.SafteyCriticalTab);
            app.PitchAngleGauge_2Label.Text = 'Pitch Angle';
            app.PitchAngleGauge_saftey = uigauge(app.SafteyCriticalTab, 'circular');
            app.PitchAngleGauge_saftey.Limits = [0 70];
            app.PitchAngleField_saftey = uieditfield(app.SafteyCriticalTab, 'numeric');
            app.RPMField_saftey = uieditfield(app.SafteyCriticalTab, 'numeric');
            app.LatestValuesTab = uitab(app.TabGroup);
            app.LatestValuesTab.Title = 'Latest Values';
            app.PreConverterCurrentLabel = uilabel(app.LatestValuesTab);
            app.PreConverterCurrentLabel.Text = 'Pre-Converter Current';
            app.PreConverterCurrentField_latest = uieditfield(app.LatestValuesTab, 'numeric');
            app.PreConverterVoltageEditFieldLabel = uilabel(app.LatestValuesTab);
            app.PreConverterVoltageEditFieldLabel.Text = 'Pre-Converter Voltage';
            app.PreConverterVoltageField_latest = uieditfield(app.LatestValuesTab, 'numeric');
            app.PostConvertCurrentEditFieldLabel = uilabel(app.LatestValuesTab);
            app.PostConvertCurrentEditFieldLabel.Text = 'Post-Convert Current';
            app.PostConvertCurrentField_latest = uieditfield(app.LatestValuesTab, 'numeric');
            app.PostConverterVoltageEditFieldLabel = uilabel(app.LatestValuesTab);
            app.PostConverterVoltageEditFieldLabel.Text = 'Post Converter Voltage';
            app.PostConverterVoltageField_latest = uieditfield(app.LatestValuesTab, 'numeric');
            app.PowerOutputEditFieldLabel = uilabel(app.LatestValuesTab);
            app.PowerOutputEditFieldLabel.Text = 'Power Output';
            app.PowerOutputField_latest = uieditfield(app.LatestValuesTab, 'numeric');
            app.TemperatureEditFieldLabel = uilabel(app.LatestValuesTab);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureField_latest = uieditfield(app.LatestValuesTab, 'numeric');
            app.PitchAngleField_latest = uieditfield(app.LatestValuesTab, 'numeric');
            app.PitchAngleGaugeLabel = uilabel(app.LatestValuesTab);
            app.PitchAngleGaugeLabel.Text = 'Pitch Angle';
            app.PitchAngleGauge_latest = uigauge(app.LatestValuesTab, 'circular');
            app.PitchAngleGauge_latest.Limits = [0 70];
            app.RPMGaugeLabel = uilabel(app.LatestValuesTab);
            app.RPMGaugeLabel.Text = 'RPM';
            app.RPMGauge_latest = uigauge(app.LatestValuesTab, 'circular');
            app.RPMGauge_latest.Limits = [0 600];
            app.RPMField_latest = uieditfield(app.LatestValuesTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WindTurbine_GUI_Eurus2026_V1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end